classdef Online_Loading < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        InitializeButton            matlab.ui.control.Button
        seg24Spinner                matlab.ui.control.Spinner
        seg24SpinnerLabel           matlab.ui.control.Label
        seg23Spinner                matlab.ui.control.Spinner
        seg23SpinnerLabel           matlab.ui.control.Label
        seg22Spinner                matlab.ui.control.Spinner
        seg22SpinnerLabel           matlab.ui.control.Label
        seg21Spinner                matlab.ui.control.Spinner
        seg21SpinnerLabel           matlab.ui.control.Label
        StopOnlineSavingButton      matlab.ui.control.Button
        StartOnlineSavingButton     matlab.ui.control.Button
        SavingLamp                  matlab.ui.control.Lamp
        SavingLampLabel             matlab.ui.control.Label
        ElectrodeAdvancedButton     matlab.ui.control.Button
        DatasetDropDown             matlab.ui.control.DropDown
        DatasetDropDownLabel        matlab.ui.control.Label
        seg14Spinner                matlab.ui.control.Spinner
        seg14SpinnerLabel           matlab.ui.control.Label
        seg13Spinner                matlab.ui.control.Spinner
        seg13SpinnerLabel           matlab.ui.control.Label
        seg12Spinner                matlab.ui.control.Spinner
        seg12SpinnerLabel           matlab.ui.control.Label
        seg11Spinner                matlab.ui.control.Spinner
        seg11SpinnerLabel           matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {713, 713};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function MonthlyPaymentButtonPushed(app, event)
                       
            % Calculate the monthly payment
            amount = app.LoanAmountEditField.Value;
            rate = app.InterestRateEditField.Value/12/100;
            nper = 12*app.LoanPeriodYearsEditField.Value;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.MonthlyPaymentEditField.Value = payment;
            
            % pre allocating and initializing variables
            interest = zeros(1,nper);
            principal = zeros(1,nper);
            balance = zeros (1,nper);
            
            balance(1) = amount;
            
            % Calculate the principal and interest over time
            for i = 1:nper
                interest(i)  = balance(i)*rate;
                principal(i) = payment - interest(i);
                balance(i+1) = balance(i) - principal(i);
            end
            
            % Plot the principal and interest
            plot(app.PrincipalInterestUIAxes,(1:nper)',principal, ...
                (1:nper)',interest);
            legend(app.PrincipalInterestUIAxes,{'Principal','Interest'},'Location','Best')
            xlim(app.PrincipalInterestUIAxes,[0 nper]); 
            ylim(app.PrincipalInterestUIAxes,'auto');
            
        end

        % Value changed function: seg11Spinner, seg12Spinner, 
        % seg13Spinner, seg14Spinner
        function StateChanged(app, event)
            value = app.seg11Spinner.Value;
        end

        % Button pushed function: StartOnlineSavingButton
        function StartOnlineSavingButtonPushed(app, event)
            app.SavingLamp.Color=[0,1,0];
        end

        % Button pushed function: StopOnlineSavingButton
        function StopOnlineSavingButtonPushed(app, event)
            app.SavingLamp.Color=[1,0,0];
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.SavingLamp.Color=[1,0,0];
            BAM_config = dir(['F:\Preload\Img_vault\matfile_pool\*mat']);
            dataset_name = {};
            for ii = 1:4
                dataset_name{end+1} = BAM_config(ii).name;
            end
            app.DatasetDropDown.Items = dataset_name;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.seg11SpinnerLabel = uilabel(app.LeftPanel);
            app.seg11SpinnerLabel.Text = 'seg1-1';
            app.seg11Spinner = uispinner(app.LeftPanel);
            app.seg11Spinner.ValueChangedFcn = createCallbackFcn(app, @StateChanged, true);
            app.seg11Spinner.Value = 1;
            app.seg12SpinnerLabel = uilabel(app.LeftPanel);
            app.seg12SpinnerLabel.Text = 'seg1-2';
            app.seg12Spinner = uispinner(app.LeftPanel);
            app.seg12Spinner.ValueChangedFcn = createCallbackFcn(app, @StateChanged, true);
            app.seg12Spinner.Value = 2;
            app.seg13SpinnerLabel = uilabel(app.LeftPanel);
            app.seg13SpinnerLabel.Text = 'seg1-3';
            app.seg13Spinner = uispinner(app.LeftPanel);
            app.seg13Spinner.ValueChangedFcn = createCallbackFcn(app, @StateChanged, true);
            app.seg13Spinner.Value = 3;
            app.seg14SpinnerLabel = uilabel(app.LeftPanel);
            app.seg14SpinnerLabel.Text = 'seg1-4';
            app.seg14Spinner = uispinner(app.LeftPanel);
            app.seg14Spinner.ValueChangedFcn = createCallbackFcn(app, @StateChanged, true);
            app.seg14Spinner.Value = 4;
            app.DatasetDropDownLabel = uilabel(app.LeftPanel);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.LeftPanel);
            app.DatasetDropDown.Items = {'Option 3', 'Option 4'};
            app.DatasetDropDown.Value = 'Option 3';
            app.ElectrodeAdvancedButton = uibutton(app.LeftPanel, 'push');
            app.ElectrodeAdvancedButton.Text = 'Electrode Advanced';
            app.SavingLampLabel = uilabel(app.LeftPanel);
            app.SavingLampLabel.Text = 'Saving';
            app.SavingLamp = uilamp(app.LeftPanel);
            app.StartOnlineSavingButton = uibutton(app.LeftPanel, 'push');
            app.StartOnlineSavingButton.ButtonPushedFcn = createCallbackFcn(app, @StartOnlineSavingButtonPushed, true);
            app.StartOnlineSavingButton.Text = 'Start Online Saving';
            app.StopOnlineSavingButton = uibutton(app.LeftPanel, 'push');
            app.StopOnlineSavingButton.ButtonPushedFcn = createCallbackFcn(app, @StopOnlineSavingButtonPushed, true);
            app.StopOnlineSavingButton.Text = 'Stop Online Saving';
            app.seg21SpinnerLabel = uilabel(app.LeftPanel);
            app.seg21SpinnerLabel.Text = 'seg2-1';
            app.seg21Spinner = uispinner(app.LeftPanel);
            app.seg22SpinnerLabel = uilabel(app.LeftPanel);
            app.seg22SpinnerLabel.Text = 'seg2-2';
            app.seg22Spinner = uispinner(app.LeftPanel);
            app.seg23SpinnerLabel = uilabel(app.LeftPanel);
            app.seg23SpinnerLabel.Text = 'seg2-3';
            app.seg23Spinner = uispinner(app.LeftPanel);
            app.seg24SpinnerLabel = uilabel(app.LeftPanel);
            app.seg24SpinnerLabel.Text = 'seg2-4';
            app.seg24Spinner = uispinner(app.LeftPanel);
            app.InitializeButton = uibutton(app.LeftPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Online_Loading

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end